classdef WebcamInterfacing1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        StartWebcamButton            matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        StartRecordingAndSaveImagesButton  matlab.ui.control.Button
        AddSaltandPepperNoiseButton  matlab.ui.control.Button
        GeneratevideowithSaltandPeppernoiseButton  matlab.ui.control.Button
        AddGausianNoiseButton        matlab.ui.control.Button
        GeneratevideowithGaussiannoiseButton  matlab.ui.control.Button
        StatusTextAreaLabel          matlab.ui.control.Label
        StatusTextArea               matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        cam; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartWebcamButton
        function StartWebcamButtonPushed(app, event)
            app.StatusTextArea.Value="Starting webcam";
            app.cam = webcam();
            frame=snapshot(app.cam);
            img =image(app.UIAxes,zeros(size(frame),'uint8'));
            axis(app.UIAxes,'image');
            
            preview(app.cam,img);
            
        end

        % Button pushed function: StartRecordingAndSaveImagesButton
        function StartRecordingAndSaveImagesButtonPushed(app, event)
            app.StatusTextArea.Value="Recording video for required duration";
            for idx = 1:450
                img = snapshot(app.cam);
                fname=['Image' num2str(idx)];
                imwrite(img,fname,'jpg');
            end
            app.StatusTextArea.Value = "Generated images from videos ";
           delete(app.cam);
           clear app.cam;
        end

        % Button pushed function: AddSaltandPepperNoiseButton
        function AddSaltandPepperNoiseButtonPushed(app, event)
            app.StatusTextArea.Value="Adding salt and pepper noise";
            for idx = 1:450
                filename = "Image"+idx;
                noiseImg = imread(filename);
                salt=imnoise(noiseImg,'salt & pepper',0.02);
                fname=['ImageWithSalt' num2str(idx)];
                imwrite(salt ,fname,'jpg');
            end
            app.StatusTextArea.Value = "Completed adding salt and pepper noise";
        end

        % Button pushed function: 
        % GeneratevideowithSaltandPeppernoiseButton
        function GeneratevideowithSaltandPeppernoiseButtonPushed(app, event)
            app.StatusTextArea.Value="Generating salt and pepper video";
            allFiles={};
            for frames = 1:450
                filename="ImageWithSalt"+frames;
                allFiles = [allFiles,filename];
            end

            movie_obj = VideoWriter('SaltAndPepper.avi');

            for K = 1 : length(allFiles)
            this_image = imread(allFiles{K});
            open(movie_obj)
            writeVideo(movie_obj, this_image);
            end

            close(movie_obj);
            app.StatusTextArea.Value="Completed salt and pepper video generation.Check project folder to access SaltAndPepper.avi";
        end

        % Button pushed function: AddGausianNoiseButton
        function AddGausianNoiseButtonPushed(app, event)
            app.StatusTextArea.Value="Adding Gausian Noise";
             for idx = 1:450
                filename = "Image"+idx;
                noiseImg = imread(filename);
                gaussian = imnoise(noiseImg,'gaussian',0, 0.02);
                fname=['ImageWithGaussian' num2str(idx)];
                imwrite(gaussian ,fname,'jpg');
             end
             app.StatusTextArea.Value="Completed adding gaussian noise";
        end

        % Button pushed function: 
        % GeneratevideowithGaussiannoiseButton
        function GeneratevideowithGaussiannoiseButtonPushed(app, event)
            app.StatusTextArea.Value="Generating Gaussian noise video";
            allFiles={};
            for frames = 1:450
                filename="ImageWithGaussian"+frames;
                allFiles = [allFiles,filename];
            end

            movie_obj = VideoWriter('GaussianVideo.avi');

            for K = 1 : length(allFiles)
            this_image = imread(allFiles{K});
            open(movie_obj)
            writeVideo(movie_obj, this_image);
            end

            close(movie_obj);
            app.StatusTextArea.Value="Completed generating gaussian video. Check project folder to access GaussianVideo.avi";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartWebcamButton = uibutton(app.UIFigure, 'push');
            app.StartWebcamButton.ButtonPushedFcn = createCallbackFcn(app, @StartWebcamButtonPushed, true);
            app.StartWebcamButton.Text = 'Start Webcam';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Recording Window')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartRecordingAndSaveImagesButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingAndSaveImagesButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingAndSaveImagesButtonPushed, true);
            app.StartRecordingAndSaveImagesButton.Text = 'Start Recording And Save Images';
            app.AddSaltandPepperNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddSaltandPepperNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddSaltandPepperNoiseButtonPushed, true);
            app.AddSaltandPepperNoiseButton.Text = 'Add Salt and Pepper Noise';
            app.GeneratevideowithSaltandPeppernoiseButton = uibutton(app.UIFigure, 'push');
            app.GeneratevideowithSaltandPeppernoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevideowithSaltandPeppernoiseButtonPushed, true);
            app.GeneratevideowithSaltandPeppernoiseButton.Text = 'Generate video with Salt and Pepper noise';
            app.AddGausianNoiseButton = uibutton(app.UIFigure, 'push');
            app.AddGausianNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddGausianNoiseButtonPushed, true);
            app.AddGausianNoiseButton.Text = 'Add Gausian Noise';
            app.GeneratevideowithGaussiannoiseButton = uibutton(app.UIFigure, 'push');
            app.GeneratevideowithGaussiannoiseButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratevideowithGaussiannoiseButtonPushed, true);
            app.GeneratevideowithGaussiannoiseButton.Text = 'Generate video with Gaussian noise';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WebcamInterfacing1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end